classdef LAT_v2_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SaveAllPanel                    matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        ImportFiguresPanel              matlab.ui.container.Panel
        original_UIAxes                 matlab.ui.control.UIAxes
        grey_UIAxes                     matlab.ui.control.UIAxes
        LengthEditField                 matlab.ui.control.NumericEditField
        UnitsEditField                  matlab.ui.control.EditField
        UnitsEditFieldLabel             matlab.ui.control.Label
        LengthEditFieldLabel            matlab.ui.control.Label
        ScaleButton                     matlab.ui.control.Button
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        ResetButton                     matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        CardiffUniversityLlywelynHughes2023Label_6  matlab.ui.control.Label
        MaskCropTab                     matlab.ui.container.Tab
        CroppedFiguresPanel             matlab.ui.container.Panel
        cropped_UIAxes                  matlab.ui.control.UIAxes
        original_UIAxes_2               matlab.ui.control.UIAxes
        AreaEditField                   matlab.ui.control.NumericEditField
        AreaEditFieldLabel              matlab.ui.control.Label
        CropButton                      matlab.ui.control.Button
        rectPanel                       matlab.ui.container.Panel
        WidthEditField                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        DrawmaskButton                  matlab.ui.control.Button
        radiusPanel                     matlab.ui.container.Panel
        RadiusEditField                 matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        FreehandButton                  matlab.ui.control.RadioButton
        PolygonButton                   matlab.ui.control.RadioButton
        RectangleButton                 matlab.ui.control.RadioButton
        CircleButton                    matlab.ui.control.RadioButton
        CardiffUniversityLlywelynHughes2023Label_5  matlab.ui.control.Label
        BinariseTab                     matlab.ui.container.Tab
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdingOptionButtonGroup   matlab.ui.container.ButtonGroup
        ManualButton                    matlab.ui.control.RadioButton
        AutomaticButton                 matlab.ui.control.RadioButton
        BinarisedFiguresPanel           matlab.ui.container.Panel
        binarised_UIAxes                matlab.ui.control.UIAxes
        histogram_UIAxes                matlab.ui.control.UIAxes
        GaussianSDSlider                matlab.ui.control.Slider
        LowerThresholdSlider            matlab.ui.control.Slider
        GaussianSDLabel                 matlab.ui.control.Label
        LowerThresholdSliderLabel       matlab.ui.control.Label
        BinariseButton                  matlab.ui.control.Button
        GeometrytoanalyseDropDown       matlab.ui.control.DropDown
        GeometrytoanalyseDropDownLabel  matlab.ui.control.Label
        CardiffUniversityLlywelynHughes2023Label_4  matlab.ui.control.Label
        AnalyseTab                      matlab.ui.container.Tab
        AnalysisUITable                 matlab.ui.control.Table
        PorosityEditField               matlab.ui.control.NumericEditField
        PorosityEditFieldLabel          matlab.ui.control.Label
        AverageRegionDiameterEditField  matlab.ui.control.NumericEditField
        AverageRegionDiameterEditFieldLabel  matlab.ui.control.Label
        AnalysisAreaThresholdSlider     matlab.ui.control.Slider
        AnalysisAreaThresholdSliderLabel  matlab.ui.control.Label
        TotalRegionAreaEditField        matlab.ui.control.NumericEditField
        TotalRegionAreaEditFieldLabel   matlab.ui.control.Label
        AverageRegionAreaEditField      matlab.ui.control.NumericEditField
        AverageRegionAreaEditFieldLabel  matlab.ui.control.Label
        ClearAnalysisButton             matlab.ui.control.Button
        AnalysisFiguresPanel            matlab.ui.container.Panel
        AnalysisFigureDropDown          matlab.ui.control.DropDown
        AnalysisFigureDropDownLabel     matlab.ui.control.Label
        region_UIAxes                   matlab.ui.control.UIAxes
        NumberofRegionsEditField        matlab.ui.control.NumericEditField
        NumberofRegionsEditFieldLabel   matlab.ui.control.Label
        AnalyseButton                   matlab.ui.control.Button
        CardiffUniversityLlywelynHughes2023Label_2  matlab.ui.control.Label
        HeatmapsTab                     matlab.ui.container.Tab
        CardiffUniversityLlywelynHughes2023Label_8  matlab.ui.control.Label
        HeatmapsButton                  matlab.ui.control.Button
        HeatmapFiguresPanel             matlab.ui.container.Panel
        periHM_UIAxes                   matlab.ui.control.UIAxes
        diaHM_UIAxes                    matlab.ui.control.UIAxes
        areaHM_UIAxes                   matlab.ui.control.UIAxes
        HistogramsTab                   matlab.ui.container.Tab
        CardiffUniversityLlywelynHughes2023Label_3  matlab.ui.control.Label
        FiguresPanel                    matlab.ui.container.Panel
        PeriHist_UIAxes                 matlab.ui.control.UIAxes
        DiaHist_UIAxes                  matlab.ui.control.UIAxes
        AreaHist_UIAxes                 matlab.ui.control.UIAxes
        ManualMeasurmentsTab            matlab.ui.container.Tab
        MeasurementsUITable             matlab.ui.control.Table
        EdgesThresholdSlider            matlab.ui.control.Slider
        EdgesThresholdLabel             matlab.ui.control.Label
        ManualMeasurementsPanel         matlab.ui.container.Panel
        PressEscwhenfinishedmeasuringLabel  matlab.ui.control.Label
        EdgeDetection_UIAxes            matlab.ui.control.UIAxes
        ClearMeasurementsButton         matlab.ui.control.Button
        DrawLineButton                  matlab.ui.control.Button
        DetectEdgesButton               matlab.ui.control.Button
        CardiffUniversityLlywelynHughes2023Label  matlab.ui.control.Label
        CADComparisonTab                matlab.ui.container.Tab
        XSlider                         matlab.ui.control.Slider
        XSlider_2Label                  matlab.ui.control.Label
        YSlider                         matlab.ui.control.Slider
        YSliderLabel                    matlab.ui.control.Label
        OverlayBoundariesButton         matlab.ui.control.Button
        ScaleCADButton                  matlab.ui.control.Button
        CardiffUniversityLlywelynHughes2023Label_7  matlab.ui.control.Label
        CADComparisonFiguresPanel       matlab.ui.container.Panel
        CAD_UIAxes                      matlab.ui.control.UIAxes
        CADFilenameEditFieldLabel       matlab.ui.control.Label
        CADFilenameEditField            matlab.ui.control.EditField
        ImportCADImageButton            matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        ResetButton_2                   matlab.ui.control.Button
        SaveAllButton                   matlab.ui.control.Button
        SaveFigureButton                matlab.ui.control.Button
        FigureDropDown                  matlab.ui.control.DropDown
        SaveMeasurementTableButton      matlab.ui.control.Button
        SaveAnalysisTableButton         matlab.ui.control.Button
        FigureExportPanel               matlab.ui.container.Panel
        CardiffUniversityLlywelynHughes2022Label_11  matlab.ui.control.Label
    end

    % © Cardiff University, Llywelyn Hughes. 2022


    properties (Access = private)
        Property % Description
        baseFileName % Base file name without extension
        
        % different views and plots of the image
        original = []; % cross sectional image
        grey_ori = []; % Original greyscale image
        grey = []; %Gaussian filtered greyscale image
        cropped_image = []; % display cropped image
        binary = []; % binary image
        ori_size = [];
        draw = []; % Edge detection image
        gcb = []; %grey colour bar
        ahcb = []; %Are Heatmap Colour Bar
        dhcb = []; %Are Heatmap Colour Bar
        phcb = []; %Are Heatmap Colour Bar
        CAD = []; % Imported CAD Image

        
        %Cropping Masks
        circle_mask = []; % Circular mask
        free_mask = []; % Freehand mask
        poly_mask = []; % Polygon mask
        rect_mask = []; % Rectangle mask
        Rthres = []; % threshold of radius of the circle mask
        c_center =[]; % center of circle
        c = []; % Circle
        r = []; % Rectangle
        poly = []; % Polygon
        free_hand = []; % Freehand       
        mask_area = []; %Area of mask
                
        scale = []; % scale to convert image pixel to mm
        pixel_size = []; %Pixel size
        
       
        lower_threshold = []; % Lower threshold for histogram
        
        crop_grey = []; % Cropped greyscale image
        crop_grey_ori = [];

        region_props = []; % Properties of detected binary regions
        number_regions = [] %Number of regions
        number_boundaries = []; %Number of boundaries
        boundaries = [];
        areaStDev = []; %Area Standard Deviation
        diaStDev = []; %Diameter Standard Deviation

        results = []; %Table for results
        t2 = []; % Table for heat map
        t2Corr = []; % Corrected table for heat map

        regionCentroid = [];
        

        perpix_dist = []; %Scaled distance per pixel
        measure_line = []; %draw lines to measure
        CADperpix_dist = [] %CAD image scaled distance per pixel
        scaleFactor = []; %CAD - original scalling factor
        x_move = []; % Translation of boundary in x direction
        y_move = []; % Translation of boundary in y direction

        figSave = [] %Figure to save
        fig_name = [];
    
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    % © Cardiff University, Llywelyn Hughes. 2022


app.UIFigure.Name = 'Lattice Analysis Tool (LAT) © Cardiff University, Llywelyn Hughes. 2022';           
        
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
             try
                %Read in and display image name
               
                [filename, pathname] = uigetfile('*.*', 'Pick an Image');
                figure(app.UIFigure); %Prevents window from hiding in background
                filepath=strcat(pathname,filename);
                app.original=imread(filepath);
                app.FilenameEditField.Value = filename;
                [~,app.baseFileName,~] = fileparts(filename);
                
                imshow(app.original,'Parent',app.original_UIAxes);
 
                [~, ~, numberOfColorChannels] = size(app.original);
                
                if numberOfColorChannels > 1
                	promptMessage = sprintf('Your image file has %d color channels.\nThis app was designed for greyscale images.\nDo you want to convert it to greyscale for you so you can continue?', numberOfColorChannels);
                	button = questdlg(promptMessage, 'Continue', 'Convert and Continue', 'Cancel', 'Convert and Continue');
                	if strcmp(button, 'Cancel')
                		sprintf(1, 'Finished running Image Analysis Tool.m.\n');
                		return;
                	end
                	% Do the conversion using standard book formula
                	app.grey = rgb2gray(app.original);

                    %% image filter and noise reduction
                    app.grey = imadjust(app.grey,stretchlim(app.grey),[0 1]);
                    app.grey = wiener2(app.grey,[5 5]);
                    %app.grey = medfilt2(app.grey,[6 6]);


                    %% Show Image
                    imshow(app.grey,'Parent',app.grey_UIAxes);
                    app.gcb = colorbar (app.grey_UIAxes,"eastoutside"); % show the colorbar
                    app.gcb.Label.String = 'greyscale value'; % label the colorbar

                else 
                    imshow(app.original,'Parent',app.grey_UIAxes);
                end
                
                
            catch
                % load a error message if there are no Images found
                errordlg('Error Loading Images','File Error');
             end
            
            app.ScaleButton.Enable = 'on';


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
              % clear all the values in the edit field
            
            app.FilenameEditField.Value = '';
            app.UnitsEditField.Value = '';
            app.LengthEditField.Value = 0;
            app.AreaEditField.Value = 0;
            app.RadiusEditField.Value = 0;
            app.WidthEditField.Value = 0;
            app.HeightEditField.Value = 0;
            app.GaussianSDSlider.Value = 0.5;
            app.LowerThresholdSlider.Value = 75;
            app.baseFileName = '';
            app.NumberofRegionsEditField.Value = 0;
            app.AverageRegionAreaEditField.Value = 0;
            app.TotalRegionAreaEditField.Value = 0;
            app.AverageRegionAreaEditField.Value = 0;
            app.PorosityEditField.Value = 0;
      
            app.CircleButton.Value = 0;
            app.RectangleButton.Value = 1;
            app.PolygonButton.Value = 0;
            app.FreehandButton.Value = 0;

            
            % disabling all the buttons
            app.GaussianSDSlider.Enable = 'off';
            app.GaussianSDLabel.Enable = 'off';
            app.EdgesThresholdLabel.Enable = 'off';
            app.EdgesThresholdSlider.Enable = 'off';

            app.CircleButton.Enable = 'off';
            app.RectangleButton.Enable = 'off';
            app.PolygonButton.Enable = 'off';
            app.CircleButton.Enable = 'off';
            app.PolygonButton.Enable = 'off';
            app.FreehandButton.Enable = 'off';
            app.DrawmaskButton.Enable = 'off';
            app.AreaEditField.Enable = 'off';
            app.AreaEditFieldLabel.Enable = 'off';
            app.RadiusEditField.Enable = 'off';
            app.RadiusEditFieldLabel.Enable = 'off';
            app.ScaleButton.Enable = 'off';
            app.UnitsEditField.Enable = 'off';
            app.UnitsEditFieldLabel.Enable = 'off';
            app.LengthEditField.Enable = 'off';
            app.LengthEditFieldLabel.Enable = 'off';
            app.HeightEditField.Enable = 'off';
            app.HeightEditFieldLabel.Enable = 'off';
            app.WidthEditField.Enable = 'off';
            app.WidthEditFieldLabel.Enable = 'off';

            app.CropButton.Enable = 'off';
            
            app.BinariseButton.Enable = 'off';
            app.LowerThresholdSlider.Enable = 'off';
            app.LowerThresholdSliderLabel.Enable = 'off';
            app.GeometrytoanalyseDropDown.Enable = 'off';
            app.GeometrytoanalyseDropDownLabel.Enable = 'off';
            
            app.AnalyseButton.Enable = 'off';
            app.HeatmapsButton.Enable = "off";
            app.AnalysisAreaThresholdSlider.Enable = "off";
            app.AnalysisAreaThresholdSliderLabel.Enable = "off";
            app.ClearAnalysisButton.Enable = 'off';
            app.NumberofRegionsEditField.Enable = 'off';
            app.NumberofRegionsEditFieldLabel.Enable = 'off';
            app.FigureDropDown.Enable = 'off';
            app.SaveFigureButton.Enable = 'off';
            app.SaveAnalysisTableButton.Enable = "off";
            app.SaveMeasurementTableButton.Enable = "off";
            app.SaveAllButton.Enable = "off";

            app.AverageRegionAreaEditField.Enable = 'off';
            app.AverageRegionAreaEditFieldLabel.Enable = 'off';
            app.TotalRegionAreaEditField.Enable = 'off';
            app.TotalRegionAreaEditFieldLabel.Enable = 'off';
            app.PorosityEditField.Enable = 'off';
            app.PorosityEditFieldLabel.Enable = 'off';
            app.AverageRegionDiameterEditField.Enable = 'off';
            app.AverageRegionDiameterEditFieldLabel.Enable = 'off';

            app.DetectEdgesButton.Enable = 'off';
            app.DrawLineButton.Enable = 'off';
            app.ClearMeasurementsButton.Enable = 'off';

            app.ThresholdingOptionButtonGroup.Enable = "off";
            app.AutomaticButton.Enable = "off";
            app.ManualButton.Enable = "off";
            app.ThresholdEditField.Enable = "off";
            app.ThresholdEditFieldLabel.Enable = "off";

            app.ImportButton.Enable = "off";
            app.CADFilenameEditField.Enable = "off";
            app.CADFilenameEditFieldLabel.Enable = "off";
            app.ScaleCADButton.Enable = "off";
            app.OverlayBoundariesButton.Enable = "off";
            app.XSlider.Enable = "off";
            app.XSlider_Label.Enable = "off";
            app.YSlider.Enable = "off";
            app.YSliderLabel.Enable = "off";

            app.original = []; % cross sectional image
            app.grey = []; %greyscale image
            app.cropped_image = []; % display cropped image
            app.binary = []; % binary image
            app.draw = []; % Edge Detection image
            
            cla(app.original_UIAxes);
            cla(app.original_UIAxes_2);
            colorbar(app.grey_UIAxes,'off');
            cla(app.grey_UIAxes);
            cla(app.cropped_UIAxes);
            cla(app.binarised_UIAxes);
            cla(app.histogram_UIAxes);
            cla(app.region_UIAxes);
            colorbar(app.areaHM_UIAxes,'off');
            cla(app.areaHM_UIAxes);
            colorbar(app.diaHM_UIAxes,'off');
            cla(app.diaHM_UIAxes);
            colorbar(app.periHM_UIAxes,'off');
            cla(app.periHM_UIAxes);
            cla(app.AreaHist_UIAxes);
            cla(app.DiaHist_UIAxes);
            cla(app.PeriHist_UIAxes);
            cla(app.EdgeDetection_UIAxes);
            cla(app.CAD_UIAxes);
            
            
            app.circle_mask = []; % mask of the circle created (2D)
            app.free_mask = []; %freehand mask
            app.poly_mask = []; %polygon mask
            app.Rthres = []; % threshold of radius of the circle mask
            app.c_center =[]; % center of circle
            app.mask_area = []; %Area of mask
            
            app.c = []; % Circle
            app.r = []; % Rectangle
            app.poly = []; % Polygon
            app.free_hand = []; % Freehand 
            
            %Clear tables
            app.AnalysisUITable.Data = [];
            app.MeasurementsUITable.Data = [];

            
            app.scale = []; % scale to convert image pixel to mm
            app.pixel_size = []; %Pixel size
            
            app.lower_threshold = []; % Lower threshold for histogram
            app.crop_grey = []; % Cropped greyscale image
            app.region_props = []; % Properties of detected binary regions
            app.number_regions = []; %Number of regions
            app.number_boundaries = []; %Number of boundaries
            app.boundaries = [];
    
            app.results = []; %Table for results
    
            app.regionCentroid = [];
    
            app.perpix_dist = []; %Scaled distance per pixel
            app.measure_line = []; %draw lines to measure
            
            
        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            app.LengthEditField.Enable = 'on';
            app.LengthEditFieldLabel.Enable = 'on';
            app.UnitsEditField.Enable = 'on';
            app.UnitsEditFieldLabel.Enable = 'on';

            % Create scaling message box
            instruction = sprintf(['Draw scaling line on Original Image ' ...
                'by clicking and dragging mouse. (Return to analyse as generic Pixel count)']);
	        button = questdlg(instruction,'Draw Scaling line',...
                'Continue','Return','Return');
            switch button

                case 'Continue'
            scale_line = drawline(app.original_UIAxes,'Color','r'); %Draw line of original Figure
            end_position = scale_line.Position; % Find x, y position of each end point
            x1 = end_position(1,1);
            y1 = end_position(1,2);
            x2 = end_position(2,1);
            y2 = end_position(2,2);
            
            %Calculate length of line in pixels
            pix_dist = sqrt( (x2-x1).^2 + (y2-y1).^2); 

            %Create popup to ask for physical distance
            prompt = {'Enter Physical distance:','Enter units (m, cm, mm, um)'};
            phys_dist = inputdlg(prompt,'Physical Distance');
            app.LengthEditField.Value = str2double(phys_dist{1});
            app.UnitsEditField.Value = phys_dist{2};

            app.perpix_dist = app.LengthEditField.Value / pix_dist;

            message = sprintf("The drawn " + app.LengthEditField.Value + " "+ app.UnitsEditField.Value + " line is " + pix_dist + " pixels" + ...
                ".\nTherefore, " + app.perpix_dist + " " + app.UnitsEditField.Value + " per pixel," + ...
                "\nand " + (1/app.perpix_dist) + " pixels per " + app.UnitsEditField.Value + "." );
            uiwait(msgbox(message));    

                case 'Return'
                    uiwait(msgbox(sprintf('Please redraw line or run program with pixel units selected')));
                    app.UnitsEditField.Value = 'pixels';
                    app.LengthEditField.Value = 1;
                    app.perpix_dist = 1;

            end

            % Change units of tables and labels
            app.LengthEditFieldLabel.Text = "Length ("+app.UnitsEditField.Value+")";
            app.AreaEditFieldLabel.Text = "Area ("+app.UnitsEditField.Value+"^2)";
            app.RadiusEditFieldLabel.Text = "Radius ("+app.UnitsEditField.Value+")";
            app.HeightEditFieldLabel.Text = "Height ("+app.UnitsEditField.Value+")";
            app.WidthEditFieldLabel.Text = "Width ("+app.UnitsEditField.Value+")";
            app.MeasurementsUITable.ColumnName = ["Line","Length (pixels)",...
                "length ("+app.UnitsEditField.Value+")"];
            app.AnalysisUITable.ColumnName = ["Region", "Mean Intensity", "Area ("+app.UnitsEditField.Value+"^2)",...
                "Perimeter ("+app.UnitsEditField.Value+")","Centroid ("+app.UnitsEditField.Value+")",...
                "Diameter ("+app.UnitsEditField.Value+")"];
            app.TotalRegionAreaEditFieldLabel.Text = "Total Region Area ("+app.UnitsEditField.Value+"^2)";
            app.AverageRegionAreaEditFieldLabel.Text = "Average Region Area ("+app.UnitsEditField.Value+"^2)";
            app.AverageRegionDiameterEditFieldLabel.Text = "Average Region Diameter ("+app.UnitsEditField.Value+")";
            xlabel(app.AreaHist_UIAxes,"Area ("+app.UnitsEditField.Value+ "^2)");
            xlabel(app.DiaHist_UIAxes,"Diameter ("+app.UnitsEditField.Value+")");
            xlabel(app.PeriHist_UIAxes,"Perimeter ("+app.UnitsEditField.Value+")");


            app.DrawmaskButton.Enable = 'on';
            app.CircleButton.Enable = 'on';
            app.RectangleButton.Enable = 'on';
            app.PolygonButton.Enable = 'on';
            app.FreehandButton.Enable = 'on';
            app.AreaEditField.Enable = 'on';
            app.AreaEditFieldLabel.Enable = 'on';
            app.RadiusEditField.Enable = 'on';
            app.RadiusEditFieldLabel.Enable = 'on';
            app.HeightEditField.Enable = 'on';
            app.HeightEditFieldLabel.Enable = 'on';
            app.WidthEditField.Enable = 'on';
            app.WidthEditFieldLabel.Enable = 'on';
            app.rectPanel.Enable = "on";


        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.AreaEditField.Value = 0;
            app.RadiusEditField.Value = 0;
            app.HeightEditField.Value = 0;
            app.WidthEditField.Value = 0;

            if (app.CircleButton.Value)
                app.radiusPanel.Visible = 'on';
                app.rectPanel.Visible = 'off';
            
            
            elseif (app.RectangleButton.Value)
                app.radiusPanel.Visible = 'off';
                app.rectPanel.Visible = 'on';

            elseif(app.FreehandButton.Value)
                app.radiusPanel.Visible = 'off';
                app.rectPanel.Visible = 'off';

            elseif (app.PolygonButton.Value)
                app.radiusPanel.Visible = 'off';
                app.rectPanel.Visible = 'off';
            end

        end

        % Button pushed function: DrawmaskButton
        function DrawmaskButtonPushed(app, event)
            
            imshow(app.original,'Parent',app.original_UIAxes_2);
            app.AreaEditField.Value = 0;
            app.RadiusEditField.Value = 0;
            app.HeightEditField.Value = 0;
            app.WidthEditField.Value = 0;
           
            if (app.CircleButton.Value)

                app.c = drawcircle (app.original_UIAxes_2, 'InteractionsAllowed','none','FaceAlpha',0.4);
                app.Rthres = app.c.Radius*app.perpix_dist;
                app.c_center = app.c.Center;  
                app.mask_area = pi*app.Rthres^2;
                app.RadiusEditField.Value = app.Rthres;

            
            elseif (app.RectangleButton.Value)
                
                app.r = drawrectangle (app.original_UIAxes_2, 'InteractionsAllowed','none','FaceAlpha',0.4);
                
                app.WidthEditField.Value = (app.r.Position (3)*app.perpix_dist); %in units chosen
                app.HeightEditField.Value = (app.r.Position (4)*app.perpix_dist); %in units chosen
                app.mask_area = app.WidthEditField.Value * app.HeightEditField.Value;

            elseif (app.PolygonButton.Value)
                app.poly = [];
                app.poly = drawpolygon(app.original_UIAxes_2, 'InteractionsAllowed','none','FaceAlpha',0.4);
                app.poly_mask = createMask(app.poly);
                area_props = regionprops(app.poly_mask,'Area');
                app.mask_area = area_props.Area*app.perpix_dist^2;


            elseif (app.FreehandButton.Value)
                app.free_hand = [];

                app.free_hand = drawfreehand(app.original_UIAxes_2, 'InteractionsAllowed','none','Closed',true,'FaceAlpha',0.4);
                app.free_mask = createMask(app.free_hand);
                area_props = regionprops(app.free_mask,'Area');
                app.mask_area = area_props.Area*app.perpix_dist^2;
            end
            format short
            app.AreaEditField.Value = app.mask_area;

            app.CropButton.Enable = 'on';

            
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
               app.cropped_UIAxes.Visible = "on";
                     
            [~, ~, numberOfColorChannels] = size(app.grey);
             if (app.CircleButton.Value)

                app.circle_mask = createMask(app.c);
                if numberOfColorChannels ==1
                    app.crop_grey = app.grey; % Initialise with the entire image
                    app.crop_grey(~app.circle_mask) = 255; %Set all area outside mask to white
                else
                    app.crop_grey = bsxfun(@times, app.grey, cast(app.circle_mask, class(app.grey)));
                end
                
                props = regionprops(app.circle_mask,'BoundingBox');
                app.crop_grey = imcrop(app.crop_grey, props.BoundingBox);
                imshow(app.crop_grey,'Parent',app.cropped_UIAxes,'Display', []);
                

             elseif (app.RectangleButton.Value)

                 app.rect_mask = createMask(app.r);

                 if numberOfColorChannels ==1
                    app.crop_grey = app.grey; % Initialise with the entire image
                else
                    app.crop_grey = bsxfun(@times, app.grey, cast(app.rect_mask, class(app.grey)));
                end
                
                props = regionprops(app.rect_mask,'BoundingBox');
                app.crop_grey = imcrop(app.crop_grey, props.BoundingBox);
                imshow(app.crop_grey,'Parent',app.cropped_UIAxes,'Display', []);


             elseif (app.PolygonButton.Value)
                app.poly_mask = createMask(app.poly);
                if numberOfColorChannels ==1
                    app.crop_grey = app.grey; % Initialise with the entire image
                    app.crop_grey(~app.poly_mask) = 255; %Set all area outside mask to white
                else
                    app.crop_grey = bsxfun(@times, app.grey, cast(app.poly_mask, class(app.grey)));
                end
                props = regionprops(app.poly_mask,'BoundingBox');
                app.crop_grey = imcrop(app.crop_grey, props.BoundingBox);
                imshow(app.crop_grey,'Parent',app.cropped_UIAxes,'Display', []);


             elseif (app.FreehandButton.Value)
                 app.free_mask = createMask(app.free_hand);
                 if numberOfColorChannels ==1
                    app.crop_grey = app.grey; % Initialise with the entire image
                    app.crop_grey(~app.free_mask) = 255; %Set all area outside mask to white
                else
                    app.crop_grey = bsxfun(@times, app.grey, cast(app.free_mask, class(app.grey)));
                 end

                props = regionprops(app.free_mask,'BoundingBox');
                app.crop_grey = imcrop(app.crop_grey, props.BoundingBox);
                imshow(app.crop_grey,'Parent',app.cropped_UIAxes,'Display', []);
                 
            end

            %% Gaussean Filter
            app.crop_grey_ori = imgaussfilt(app.crop_grey,app.GaussianSDSlider.Value);% Filtered image
            

app.GeometrytoanalyseDropDown.Enable = 'on';
app.GeometrytoanalyseDropDownLabel.Enable = 'on';
app.LowerThresholdSlider.Enable = 'on';
app.LowerThresholdSliderLabel.Enable = 'on';
app.BinariseButton.Enable = 'on';
app.GaussianSDSlider.Enable = 'on';
app.GaussianSDLabel.Enable = 'on';
app.ThresholdingOptionButtonGroup.Enable = 'on';
app.AutomaticButton.Enable = 'on';
app.ManualButton.Enable = 'on';
app.ThresholdEditField.Enable = 'on';
app.ThresholdEditFieldLabel.Enable = 'on';


        end

        % Value changed function: GaussianSDSlider
        function GaussianSDSliderValueChanged(app, event)
 
            app.crop_grey = imgaussfilt(app.crop_grey_ori,app.GaussianSDSlider.Value);


            % plotting the greyscale image
            %imshow(app.crop_grey_ori, 'Parent',app.cropped_UIAxes ,'Display',[]);
            BinariseButtonPushed(app, event);

        end

        % Callback function: BinariseButton, LowerThresholdSlider
        function BinariseButtonPushed(app, event)
           %% GaussianSDSliderValueChanged(app, event);
           format short
           switch app.ThresholdingOptionButtonGroup.SelectedObject.Text
               case 'Automatic'  %Automatic thresholdign with the Otsu method
                   app.LowerThresholdSlider.Enable = 'off';
                   app.LowerThresholdSliderLabel.Enable = 'off';
                   app.lower_threshold = graythresh(app.crop_grey)*100;
                   app.ThresholdEditField.Value = app.lower_threshold;

               case 'Manual' %Manual threshoding with slider
                   app.LowerThresholdSlider.Enable = 'on';
                   app.LowerThresholdSliderLabel.Enable = 'on';
                   app.lower_threshold = app.LowerThresholdSlider.Value;
                   app.ThresholdEditField.Value = app.lower_threshold;


           end
           
           app.binary = imgaussfilt(app.crop_grey_ori,app.GaussianSDSlider.Value);


           %% Binarise Filtered Image
            switch app.GeometrytoanalyseDropDown.Value
                case 'Light' % To Analyse the ligth sections of greyscale image
                    app.binary = app.crop_grey > app.lower_threshold;

                case 'Dark' % To analyse the dark sections of greyscale image
                    app.binary = app.crop_grey < app.lower_threshold;
                    
            end
            app.binary = imfill(app.binary,'holes');
            %app.binary = imclearborder(app.binary,4); %Fills in borders. ***Note: This method does increase uncertainty for porosity calcs as some regions are not included.***
            %app.binary = padarray(app.binary,[2 2]);
            
           % [app.binary,~] = bwlabel(app.binary, 8);     % Label each region so we can make measurements of it

            
            imshow(app.binary,'Parent',app.binarised_UIAxes,'Display', []);

%% Histogram
            app.histogram_UIAxes.Visible = "on";
            histogram(app.histogram_UIAxes,app.crop_grey,255,LineWidth=0.05);

            %Show Threshold on histogram
            format short
            maxYValue = ylim(app.histogram_UIAxes);
            line(app.histogram_UIAxes,[app.lower_threshold, app.lower_threshold], maxYValue, 'Color', 'r',LineWidth=2);
            % Place a text label on the bar chart showing the threshold.
            annotationText = "Thresholded value: " + app.lower_threshold;
            % For text(), the x and y need to be of the data class "double" so let's cast both to double.
            text(app.histogram_UIAxes,double(app.lower_threshold + 5), double(0.5 * maxYValue(2)), annotationText, 'FontSize', 12, 'Color', [0 .5 0],'FontWeight','bold');
            text(app.histogram_UIAxes,double(app.lower_threshold - 40), double(0.9 * maxYValue(2)), 'Background', 'FontSize', 12, 'Color', [0 0 .5],'FontWeight','bold');
            text(app.histogram_UIAxes,double(app.lower_threshold + 40), double(0.9 * maxYValue(2)), 'Foreground', 'FontSize', 12, 'Color', [0 0 .5],'FontWeight','bold');


            app.AnalyseButton.Enable = 'on';
            app.NumberofRegionsEditField.Enable = 'on';
            app.NumberofRegionsEditFieldLabel.Enable = 'on';
            app.AnalysisUITable.Enable = 'on';
            app.TotalRegionAreaEditField.Enable = 'on';
            app.TotalRegionAreaEditFieldLabel.Enable = 'on';
            app.PorosityEditField.Enable = 'on';
            app.PorosityEditFieldLabel.Enable = 'on';
            app.AverageRegionAreaEditField.Enable = 'on';
            app.AverageRegionAreaEditFieldLabel.Enable = 'on';
            app.AverageRegionDiameterEditField.Enable = 'on';
            app.AverageRegionDiameterEditFieldLabel.Enable = 'on';
            app.SaveAnalysisTableButton.Enable = 'on';
            app.SaveFigureButton.Enable = 'on';
            app.ImportCADImageButton.Enable = 'on';
            app.CADFilenameEditField.Enable = 'on';
            app.CADFilenameEditFieldLabel.Enable = 'on';


        end

        % Callback function: AnalyseButton, AnalysisAreaThresholdSlider
        function AnalyseButtonPushed(app, event)
            %Get all properties for regions

            app.ClearAnalysisButton.Enable = 'on';
            app.AnalysisUITable.Data = [];
            heatTbl = [];

            app.AnalysisFigureDropDown.Visible = "on";
            app.AnalysisFigureDropDownLabel.Visible = "on";
            app.AreaHist_UIAxes.Visible = "on";
            app.DiaHist_UIAxes.Visible = "on";
            app.PeriHist_UIAxes.Visible = "on";
            app.AnalysisAreaThresholdSlider.Enable = "on";
            app.AnalysisAreaThresholdSliderLabel.Enable = "on";
            
            areaThreshold = round(app.AnalysisAreaThresholdSlider.Value);

            threshMap= bwareaopen(app.binary,areaThreshold); %Removes areas of area less than 10 pixels
            app.region_props = regionprops(threshMap,app.crop_grey,'all'); 
            app.number_regions = numel(app.region_props);

            app.NumberofRegionsEditField.Value = app.number_regions; % Show total number of regions
      
            
            %------------------------------------------------------------------------------------------------------------------------------------------------------
             
            % Extract and display all the properties into UITable
            % The "diameter" is the "Equivalent Circular Diameter", which is the diameter of a circle with the same number of pixels as the region.            
            app.results = app.AnalysisUITable.Data;

            switch  app.AnalysisFigureDropDown.Value
                case "Binarised"
                    imshow(app.binary,'Parent',app.region_UIAxes);
                case "Cropped"
                    imshow(app.crop_grey,'Parent',app.region_UIAxes);
            end
            hold(app.region_UIAxes, 'on')
            
            
            for k = 1 : app.number_regions           % Loop through all regions.
                % Find the individual measurements of each region.  They are field of each structure in the props strucutre array.
                meanGL = app.region_props(k).MeanIntensity;		% Get average intensity.
                regionArea = app.region_props(k).Area  * app.perpix_dist^2;				% Get area.
                regionPerimeter = app.region_props(k).Perimeter * app.perpix_dist;		% Get perimeter.
                app.regionCentroid = app.region_props(k).Centroid * app.perpix_dist;		% Get centroid one at a time
                regionCentroid1 = app.region_props(k).Centroid;
                regionECD = app.region_props(k).EquivDiameter * app.perpix_dist;
                

                vars = {num2str(k) num2str(meanGL) num2str(regionArea) num2str(regionPerimeter) num2str(app.regionCentroid) ...
                    num2str(regionECD)};

                app.AnalysisUITable.Data = [app.AnalysisUITable.Data; vars];
                scroll(app.AnalysisUITable,'bottom');

                heatVars = {num2str(app.regionCentroid(1)) num2str(app.regionCentroid(2)) num2str(regionArea) ...
                    num2str(k) num2str(regionECD) num2str(regionPerimeter)};

                heatTbl = [heatTbl; heatVars];      


                labels = text(app.region_UIAxes, regionCentroid1(1), regionCentroid1(2), num2str(k), 'FontSize', 14,...
                    'Color','blue','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
                uistack(labels,"top");
            end


            %% Show Boundaries

             % Get the boundaries for each region.
             app.boundaries = bwboundaries(threshMap,'noholes'); % Note: this is a cell array with several boundaries -- one boundary per region.
             % boundaries is a cell array - one cell for each region.
             % In each cell is an N-by-2 list of coordinates in a (row, column) format.  Note: NOT (x,y).
             % Column 1 is rows, or y.    Column 2 is columns, or x.
             %app.boundaries = imfill(app.boundaries,'holes');
             app.number_boundaries = size(app.boundaries, 1); % Count the boundaries so we can use it in our for loop

             for k = 1 : app.number_boundaries
                 thisBoundary = app.boundaries{k}; % Boundry of region
                 x = thisBoundary(:,2); % Column 2 is the columns, which is x.
                 y = thisBoundary(:,1); % Column 1 is the rows, which is y.

                 bound = plot(app.region_UIAxes,x, y, 'r-', 'LineWidth', 2); % Plot boundary in red.


             end
             uistack(bound,"down");
             hold(app.region_UIAxes, 'off');

            
%% Analyse Data
            t1 = str2double(app.AnalysisUITable.Data); %Convert string table to double values
            app.t2 = str2double(heatTbl);
            app.t2Corr = rmoutliers(app.t2,"mean"); %Removed outliers

            %calculate sums
            total = sum(t1); 
            app.TotalRegionAreaEditField.Value = total(3); 
            porosity = app.TotalRegionAreaEditField.Value / app.AreaEditField.Value;
            %porosity1 = 1-(app.TotalRegionAreaEditField.Value / app.AreaEditField.Value);
            app.PorosityEditField.Value = porosity*100;

            %Calculate averages  
            average = mean(t1);
            app.AverageRegionAreaEditField.Value = average(3);
            app.AverageRegionDiameterEditField.Value = average(6);

            %Calcualte Standard Deviations
            stDev = std(t1);
            app.areaStDev = stDev(3);
            app.diaStDev = stDev(6);

%% Histograms

            %Area Histogram
            histogram(app.AreaHist_UIAxes,t1(:,3),15); % Area Histogram
            histogram(app.DiaHist_UIAxes,t1(:,6),15); % Diameter Histogram
            histogram(app.PeriHist_UIAxes,t1(:,4),15);% Perimeter Histogram


            app.MeasurementsUITable.Enable = 'on';
            app.HeatmapsButton.Enable = 'on';
            app.FigureDropDown.Enable = 'on';
            app.EdgesThresholdLabel.Enable = 'on';
            app.EdgesThresholdSlider.Enable = 'on';
            app.DetectEdgesButton.Enable = 'on';
            app.SaveAllButton.Enable = "on";
            app.FigureDropDown.Enable = "on";

        end

        % Value changed function: AnalysisFigureDropDown
        function AnalysisFigureDropDownValueChanged(app, event)
            AnalyseButtonPushed(app, event);
        end

        % Button pushed function: HeatmapsButton
        function HeatmapsButtonPushed(app, event)
            %% Area Heat Map

            %hold (app.areaHM_UIAxes, 'on');
            app.areaHM_UIAxes.Visible = "on";
            hwb = waitbar(0.3);

            x = linspace(min(app.t2Corr(:,1)),max(app.t2Corr(:,1)),500);
            y = linspace(min(app.t2Corr(:,2)),max(app.t2Corr(:,2)),500);
            [X,Y] = meshgrid(x,y);
            F=scatteredInterpolant(app.t2Corr(:,1),app.t2Corr(:,2),app.t2Corr(:,3),"nearest");
            contourf(app.areaHM_UIAxes,X,Y,F(X,Y),100,"LineColor","none");

            xlabel(app.areaHM_UIAxes,"X Coordinate ("+app.UnitsEditField.Value+")");
            ylabel(app.areaHM_UIAxes,"Y Coordinate ("+app.UnitsEditField.Value+")");
            title(app.areaHM_UIAxes,"Area Heatmap");

            app.ahcb = colorbar(app.areaHM_UIAxes);
            colormap(app.areaHM_UIAxes,"jet");
            clim(app.areaHM_UIAxes,[min(app.t2Corr(:,3)) max(app.t2Corr(:,3))]);
            app.ahcb.Label.String = "Area ("+app.UnitsEditField.Value+"^2)";
            set(app.areaHM_UIAxes,'YDir','reverse');


            %Set labels for regions
            x_co = app.t2(:,1);
            y_co = app.t2(:,2);
            num = app.t2(:,4);

            for j = 1:length(x_co)
                text(app.areaHM_UIAxes,x_co(j), y_co(j) ,num2str(num(j)), 'FontSize', 10, ...
                    'Color','black','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
            end

            %% Diameter Heat Map
            waitbar(0.6,hwb);
            app.diaHM_UIAxes.Visible = "on";
            title(app.diaHM_UIAxes, 'Diameter Heatmap Image');
            xlabel(app.diaHM_UIAxes,"X Coordinate ("+app.UnitsEditField.Value+")");
            ylabel(app.diaHM_UIAxes,"Y Coordinate ("+app.UnitsEditField.Value+")");
            app.dhcb = colorbar(app.diaHM_UIAxes);
            app.dhcb.Label.String = "Diameter ("+app.UnitsEditField.Value+")";
            colormap(app.diaHM_UIAxes,"jet");
            clim(app.diaHM_UIAxes, [min(app.t2Corr(:,5)) max(app.t2Corr(:,5))]);
            set(app.diaHM_UIAxes,'YDir','reverse');


            x = linspace(min(app.t2Corr(:,1)),max(app.t2Corr(:,1)),500);
            y = linspace(min(app.t2Corr(:,2)),max(app.t2Corr(:,2)),500);
            [X,Y] = meshgrid(x,y);
            F=scatteredInterpolant(app.t2Corr(:,1),app.t2Corr(:,2),app.t2Corr(:,5),"nearest");
            contourf(app.diaHM_UIAxes,X,Y,F(X,Y),100,"LineColor","none");

            %Set labels for regions
            x_co = app.t2(:,1);
            y_co = app.t2(:,2);
            num = app.t2(:,4);

            for j = 1:length(x_co)
                text(app.diaHM_UIAxes,x_co(j), y_co(j) ,num2str(num(j)), 'FontSize', 10,...
                    'Color','black','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
            end


            %% Perimeter Heat Map
            waitbar(0.9,hwb);
            app.periHM_UIAxes.Visible = "on";
            title(app.periHM_UIAxes, 'Perimeter Heatmap Image');
            xlabel(app.periHM_UIAxes,"X Coordinate ("+app.UnitsEditField.Value+")");
            ylabel(app.periHM_UIAxes,"Y Coordinate ("+app.UnitsEditField.Value+")");
            app.phcb = colorbar(app.periHM_UIAxes);
            app.phcb.Label.String = "Perimeter ("+app.UnitsEditField.Value+")";
            colormap(app.periHM_UIAxes,"jet");
            clim(app.periHM_UIAxes, [min(app.t2Corr(:,6)) max(app.t2Corr(:,6))]);
            set(app.periHM_UIAxes,'YDir','reverse');


            x = linspace(min(app.t2Corr(:,1)),max(app.t2Corr(:,1)),500);
            y = linspace(min(app.t2Corr(:,2)),max(app.t2Corr(:,2)),500);
            [X,Y] = meshgrid(x,y);
            F=scatteredInterpolant(app.t2Corr(:,1),app.t2Corr(:,2),app.t2Corr(:,6),"nearest");
            contourf(app.periHM_UIAxes,X,Y,F(X,Y),100,"LineColor","none");

            %Set labels for regions
            x_co = app.t2(:,1);
            y_co = app.t2(:,2);
            num = app.t2(:,4);

            for j = 1:length(x_co)
                text(app.periHM_UIAxes,x_co(j), y_co(j) ,num2str(num(j)), 'FontSize', 10,...
                    'Color','black','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
            end
            close(hwb);
        end

        % Button pushed function: ClearAnalysisButton
        function ClearAnalysisButtonPushed(app, event)
            app.AnalysisUITable.Data = [];


        end

        % Callback function: DetectEdgesButton, EdgesThresholdSlider
        function DetectEdgesButtonPushed(app, event)
            %Use Edge detector function
            
            app.draw = edge(app.crop_grey,'canny',app.EdgesThresholdSlider.Value);
            imshow(app.draw, 'Parent',app.EdgeDetection_UIAxes);
            

            app.DrawLineButton.Enable = 'on';
            app.SaveMeasurementTableButton.Enable = "on";
            app.ClearMeasurementsButton.Enable = 'on';
        end

        % Button pushed function: DrawLineButton
        function DrawLineButtonPushed(app, event)
            
            app.MeasurementsUITable.Enable = 'on';
            
            uiwait(msgbox(sprintf(['Click and drag to draw line on the Drawn Image' ...
                '.\nPress Escape on keyboard once finished.'])));

            l = char(97:122); % character array for line I.D.


            for nl = 1:length(l) %Loop through character array
                try
                    %l(nl) to call up letter for line

                    app.measure_line = drawline(app.EdgeDetection_UIAxes,'color','r',...
                        'Label',num2str(l(nl)));
                    end_position = app.measure_line.Position; % Find x, y position of each end point
                    x1 = end_position(1,1);
                    y1 = end_position(1,2);
                    x2 = end_position(2,1);
                    y2 = end_position(2,2);

                    %Calculate length of line in pixels
                    pix_dist = sqrt( (x2-x1).^2 + (y2-y1).^2);

                    %Calculate length of line in "units"
                    line_length = pix_dist*app.perpix_dist;

                    %Show data in table
                    vars = {num2str(l(nl)) num2str(pix_dist) num2str(line_length)};
                    app.MeasurementsUITable.Data = [app.MeasurementsUITable.Data; vars];
                    scroll(app.MeasurementsUITable,'bottom');
                    app.PressEscwhenfinishedmeasuringLabel.Visible = "on";
                catch
                    break;
                end
            end

        app.PressEscwhenfinishedmeasuringLabel.Visible = "off";
        app.SaveAllButton.Enable = "on";
        app.SaveMeasurementTableButton.Enable = "on";


        end

        % Button pushed function: ClearMeasurementsButton
        function ClearMeasurementsButtonPushed(app, event)
            app.MeasurementsUITable.Data = [];
            imshow(app.draw,'Parent',app.EdgeDetection_UIAxes,'Display', []);            
        end

        % Value changed function: FigureDropDown
        function FigureDropDownValueChanged(app, event)
            
            figSel = app.FigureDropDown.Value;
            delete(app.FigureExportPanel.Children)

            figLocation = [50 50 500 500];
            switch figSel
                case "Original"
                    % copying the images from the app to new figure
                    app.figSave = copyobj ([app.original_UIAxes],app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.original_UIAxes;


                case "Greyscale"
                    % copying the images from the app to new figure
                    app.figSave = copyobj ([app.grey_UIAxes, app.gcb], app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.grey_UIAxes;
                    
                case "Cropped"
                    % copying the images from the app to new figure
                    app.figSave = copyobj ([app.cropped_UIAxes],app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.cropped_UIAxes;

                case "Binary"
                    % copying the images from the app to new figure
                    app.figSave = copyobj ([app.binarised_UIAxes],app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.binarised_UIAxes;

                case "Region"
                    % copying the images from the app to new figure
                    app.figSave = copyobj ([app.region_UIAxes],app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.region_UIAxes;

                case "Heatmap (Area)"
                    % copying the images from the app to new figure
                    app.figSave = copyobj([app.areaHM_UIAxes, app.ahcb],app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.areaHM_UIAxes;

                case "Heatmap (Diameter)"
                    % copying the images from the app to new figure
                    app.figSave = copyobj([app.diaHM_UIAxes, app.dhcb],app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.diaHM_UIAxes;

                case "Heatmap (Perimeter)"
                    % copying the images from the app to new figure
                    app.figSave = copyobj([app.periHM_UIAxes, app.phcb],app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.periHM_UIAxes;

                case "Area Histogram"
                    % copying the images from the app to new figure
                    app.figSave = copyobj([app.AreaHist_UIAxes],app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.AreaHist_UIAxes;

                case "Diameter Histogram"
                    % copying the images from the app to new figure
                    app.figSave = copyobj([app.DiaHist_UIAxes],app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.DiaHist_UIAxes;

                case "Perimeter Histogram"
                    % copying the images from the app to new figure
                    app.figSave = copyobj([app.PeriHist_UIAxes],app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.PeriHist_UIAxes;

                case "Manual Measurements"
                    % copying the images from the app to new figure
                    app.figSave = copyobj([app.EdgeDetection_UIAxes],app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.EdgeDetection_UIAxes;

                case "Histogram"
                    % copying the images from the app to new figure
                    app.figSave = copyobj([app.histogram_UIAxes],app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.histogram_UIAxes;

                case "CAD"
                    % copying the images from the app to new figure
                    app.figSave = copyobj([app.CAD_UIAxes],app.FigureExportPanel);
                    set(app.figSave,'Position',figLocation);
                    app.fig_name = app.CAD_UIAxes;

            end
            
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
             try
                
                filter = {'*.png';'*.jpg';'*.tif';'*.pdf';'*.eps';'*.fig'};
                [filename,filepath] = uiputfile(filter);


                if ischar(filename) % ensure that there is a filename input
                    
                    % save the figure
                    exportgraphics(app.fig_name,[filepath filename],'Resolution',300); % (app.fig,[....
                    
                    % inform user that figure has been saved
                    msgbox (app.FigureDropDown.Value +" image saved","success");
                end


            catch
               errordlg("File not saved","File Error");
            end

        end

        % Button pushed function: SaveAnalysisTableButton
        function SaveAnalysisTableButtonPushed(app, event)
                try
                filter = {'.csv';'.xlsx'};
                [filename,filepath] = uiputfile(filter,'Save Analysis Table');
                fullfilename = fullfile(filepath, filename);
                
                if ischar(filename)
                    region = app.AnalysisUITable.Data(:,1);
                    intensity = app.AnalysisUITable.Data(:,2);
                    area = app.AnalysisUITable.Data(:,3);
                    perim = app.AnalysisUITable.Data(:,4);
                    cent = app.AnalysisUITable.Data(:,5);
                    dia = app.AnalysisUITable.Data(:,6);

                    data = horzcat(region,intensity,area, perim,cent,dia);
                    col_name = ["Region", "mean Intensity","Area ("+app.UnitsEditField.Value+"^2)",...
                        "Perimeter ("+app.UnitsEditField.Value+")", "Centroid ("+app.UnitsEditField.Value+")",...
                        "Diameter ("+app.UnitsEditField.Value+")"]; %Include column names
                    
                    data = array2table (data,'VariableNames', col_name);
                    
                    writetable(data, fullfilename);                   
                    msgbox ('File saved','success');
                end
                
                catch
                
                errordlg('File not saved','File Error');
                end
        end

        % Button pushed function: SaveMeasurementTableButton
        function SaveMeasurementsTableButtonPushed(app, event)
           try
                filter = {'.csv';'.xlsx'};
                [filename,filepath] = uiputfile(filter,'Save Measurement Table');
                fullfilename = fullfile(filepath, filename);
                
                if ischar(filename)
                    label = app.MeasurementsUITable.Data(:,1);
                    pix_meas = app.MeasurementsUITable.Data(:,2);
                    unit_meas = app.MeasurementsUITable.Data(:,3);

                    data = horzcat(label,pix_meas,unit_meas);
                    col_name = ["Line", "Length (Pixels)","Length ("+app.UnitsEditField.Value+")"]; %Include column names
                    
                    data = array2table (data,'VariableNames', col_name);
                    
                    writetable(data, fullfilename);                   
                    msgbox ('File saved','success');
                end
                
            catch
                
                errordlg('File not saved','File Error');
            end
        end

        % Button pushed function: SaveAllButton
        function SaveAllButtonPushed(app, event)
           try
      
                selPath = uigetdir();
                fileName = app.baseFileName;
                fullFileName = fullfile(selPath, fileName);
                
                wb = waitbar(0,'Saving Data...'); %Load Waitbar

                if ischar(fileName)
                    %% Save Analysis Table
                    wb = waitbar(0.1,wb,'Saving Data...');
                    region = app.AnalysisUITable.Data(:,1);
                    intensity = app.AnalysisUITable.Data(:,2);
                    area = app.AnalysisUITable.Data(:,3);
                    perim = app.AnalysisUITable.Data(:,4);
                    cent = app.AnalysisUITable.Data(:,5);
                    dia = app.AnalysisUITable.Data(:,6);

                    analysisData = horzcat(region,intensity,area, perim,cent,dia);
                    analysisColName = ["Region", "mean Intensity","Area ("+app.UnitsEditField.Value+"^2)",...
                        "Perimeter ("+app.UnitsEditField.Value+")", "Centroid ("+app.UnitsEditField.Value+")",...
                        "Diameter ("+app.UnitsEditField.Value+")"]; %Include column names
                    
                    analysisData = array2table (analysisData,'VariableNames', analysisColName);
                    
                    writetable(analysisData, fullFileName+"_tables.xlsx","Sheet","Analysis");

                    wb = waitbar(0.2,wb,'Saving Data...');
                    
                    %% Save porosity analysis data
                    dataName = ["Average Region Area ("+app.UnitsEditField.Value+"^2)", "Total Region Area ("+app.UnitsEditField.Value+"^2)","Area Std. Dev ("+app.UnitsEditField.Value+"^2)",...
                        "Average Region Diameter ("+app.UnitsEditField.Value+")","Diameter Std Dev. ("+app.UnitsEditField.Value+")","Porosity (%)"];
                    porData = [app.AverageRegionAreaEditField.Value, app.TotalRegionAreaEditField.Value, app.areaStDev, ...
                        app.AverageRegionDiameterEditField.Value, app.diaStDev,app.PorosityEditField.Value];
                    dataTable = array2table(porData,'VariableNames',dataName);
                    writetable(dataTable, fullFileName+"_tables.xlsx","Sheet","Porosity Analysis")


                    %%  Save Measurement Table
                    label = app.MeasurementsUITable.Data(:,1);
                    pix_meas = app.MeasurementsUITable.Data(:,2);
                    unit_meas = app.MeasurementsUITable.Data(:,3);

                    measurementData = horzcat(label,pix_meas,unit_meas);
                    measurementColName = ["Line", "Length (Pixels)","Length ("+app.UnitsEditField.Value+")"]; %Include column names
                    measurementData = array2table (measurementData,'VariableNames', measurementColName);
                    writetable(measurementData, fullFileName+"_tables.xlsx","Sheet","Measurements");   

                    wb = waitbar(0.25,wb,'Saving Data...');

                    %% Save Figures
                    figList = [app.original_UIAxes, app.cropped_UIAxes, app.grey_UIAxes,app.EdgeDetection_UIAxes, app.region_UIAxes, app.histogram_UIAxes, app.AreaHist_UIAxes, app.DiaHist_UIAxes, app.PeriHist_UIAxes, app.areaHM_UIAxes, app.diaHM_UIAxes, app.periHM_UIAxes, app.CAD_UIAxes];
                    figNameList= {'_Original.png','_Cropped.png','_Greyscale.png','_ManualMeasure.png','_BinarisedImage.png','_Histogram.png','_AreaHist.png','_DiaHist.png','_PeriHist.png','_AreaHM.png','_DiaHM.png','_PeriHM.png','_CAD.png'};


                    for i = 1:length(figList)
                        wb = waitbar(0.2+0.075*i,wb,'Saving Data...');

                        % save the figure
                        figName = char(strcat(fileName,figNameList(i)));
                        figPath = char(strcat(selPath,'\')); %
                        exportgraphics(figList(i),[figPath figName]);
                        %delete(fig) % delete the new figure
                    end

                    close(wb);
                    msgbox ('Files saved','success');
                end
                
            catch
                
                errordlg('File not saved','File Error');
          end
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            ResetButtonPushed(app, event);
            app.TabGroup.SelectedTab = app.ImportTab;
        end

        % Button pushed function: ImportCADImageButton
        function ImportCADImageButtonPushed(app, event)

            %Read in and display image name
            [CADfilename, pathname] = uigetfile('*.*', 'Pick an Image');
            figure(app.UIFigure); %Prevents window from hiding in background
            CADfilepath=strcat(pathname,CADfilename);
            app.CAD=imread(CADfilepath);
            app.CADFilenameEditField.Value = CADfilename;
            [~,app.baseFileName,~] = fileparts(CADfilename);

            imshow(app.CAD,'Parent',app.CAD_UIAxes);

            app.ScaleCADButton.Enable = 'on';
           
            
        end

        % Button pushed function: ScaleCADButton
        function ScaleCADButtonPushed(app, event)
              % Create scaling message box
            instruction = sprintf(['Draw scaling line on CAD Image ' ...
                'by clicking and dragging mouse. (Original Image must also be scalled for comparison)']);
	        button = questdlg(instruction,'Draw Scaling line',...
                'Continue','Return','Return');
            switch button

                case 'Continue'
            scale_line = drawline(app.CAD_UIAxes,'Color','r'); %Draw line of original Figure
            end_position = scale_line.Position; % Find x, y position of each end point
            x1 = end_position(1,1);
            y1 = end_position(1,2);
            x2 = end_position(2,1);
            y2 = end_position(2,2);
            
            %Calculate length of line in pixels
            pix_dist = sqrt( (x2-x1).^2 + (y2-y1).^2); 

            %Create popup to ask for physical distance
            prompt = {'Enter Physical distance:','Enter units (m, cm, mm, um)'};
            phys_dist = inputdlg(prompt,'Physical Distance');
            app.LengthEditField.Value = str2double(phys_dist{1});
            app.UnitsEditField.Value = phys_dist{2};

            app.CADperpix_dist = app.LengthEditField.Value / pix_dist;

            message = sprintf("The drawn " + app.LengthEditField.Value + " "+ app.UnitsEditField.Value + " line is " + pix_dist + " pixels" + ...
                ".\nTherefore, " + app.perpix_dist + " " + app.UnitsEditField.Value + " per pixel," + ...
                "\nand " + (1/app.perpix_dist) + " pixels per " + app.UnitsEditField.Value + "." );
            uiwait(msgbox(message));    

                case 'Return'
                    uiwait(msgbox(sprintf('Please redraw line or run program with pixel units selected')));
                    app.UnitsEditField.Value = 'pixels';
                    app.LengthEditField.Value = 1;
                    app.perpix_dist = 1;

            end

            app.scaleFactor = app.perpix_dist/app.CADperpix_dist; %Scalling factor for boundary overaly


            app.OverlayBoundariesButton.Enable = "on";
        end

        % Button pushed function: OverlayBoundariesButton
        function OverlayBoundariesButtonPushed(app, event)
            
            imshow(app.CAD,'Parent',app.CAD_UIAxes);
            hold (app.CAD_UIAxes, "on");

            for k = 1 : app.number_boundaries
                 thisBoundary = app.boundaries{k}; % Boundry of region
                 x = (thisBoundary(:,2)+app.XSlider.Value)*app.scaleFactor; % Column 2 is the columns, which is x.
                 y = (thisBoundary(:,1)+app.YSlider.Value)*app.scaleFactor; % Column 1 is the rows, which is y.

                 bound = plot(app.CAD_UIAxes,x, y, 'r-', 'LineWidth', 2); % Plot boundary in red.
            end

            uistack(bound,"down");
            hold(app.CAD_UIAxes, 'off');

            app.XSlider.Enable = "on";
            app.XSlider_2Label.Enable = "on";
            app.YSlider.Enable = "on";
            app.YSliderLabel.Enable = "on";

            %Adjust limits of slider
            [height,~] = size(app.CAD);
            app.XSlider.Limits = [0,500];
            app.YSlider.Limits = [0,height];
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            app.x_move = app.XSlider.Value;
            OverlayBoundariesButtonPushed(app, event);
            
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            app.y_move = app.YSlider.Value;
            OverlayBoundariesButtonPushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'turbo');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.CardiffUniversityLlywelynHughes2023Label_6 = uilabel(app.ImportTab);
            app.CardiffUniversityLlywelynHughes2023Label_6.Text = '© Cardiff University, Llywelyn Hughes. 2023';
            app.ImportButton = uibutton(app.ImportTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ResetButton = uibutton(app.ImportTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.FilenameEditField = uieditfield(app.ImportTab, 'text');
            app.FilenameEditField.Editable = 'off';
            app.FilenameEditFieldLabel = uilabel(app.ImportTab);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.ScaleButton = uibutton(app.ImportTab, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Enable = 'off';
            app.ScaleButton.Text = 'Scale';
            app.LengthEditFieldLabel = uilabel(app.ImportTab);
            app.LengthEditFieldLabel.Enable = 'off';
            app.LengthEditFieldLabel.Text = 'Length ';
            app.UnitsEditFieldLabel = uilabel(app.ImportTab);
            app.UnitsEditFieldLabel.Enable = 'off';
            app.UnitsEditFieldLabel.Text = 'Units';
            app.UnitsEditField = uieditfield(app.ImportTab, 'text');
            app.UnitsEditField.Editable = 'off';
            app.UnitsEditField.Enable = 'off';
            app.LengthEditField = uieditfield(app.ImportTab, 'numeric');
            app.LengthEditField.Editable = 'off';
            app.LengthEditField.Enable = 'off';
            app.ImportFiguresPanel = uipanel(app.ImportTab);
            app.ImportFiguresPanel.Title = 'Import Figures';
            app.grey_UIAxes = uiaxes(app.ImportFiguresPanel);
            title(app.grey_UIAxes, 'Greyscale Image')
            app.grey_UIAxes.Visible = 'off';
            app.original_UIAxes = uiaxes(app.ImportFiguresPanel);
            title(app.original_UIAxes, 'Original Image')
            app.original_UIAxes.Visible = 'off';
            app.Image = uiimage(app.ImportTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'LAT_Logo.PNG');
            app.MaskCropTab = uitab(app.TabGroup);
            app.MaskCropTab.Title = 'Mask & Crop';
            app.CardiffUniversityLlywelynHughes2023Label_5 = uilabel(app.MaskCropTab);
            app.CardiffUniversityLlywelynHughes2023Label_5.Text = '© Cardiff University, Llywelyn Hughes. 2023';
            app.ButtonGroup = uibuttongroup(app.MaskCropTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.CircleButton = uiradiobutton(app.ButtonGroup);
            app.CircleButton.Enable = 'off';
            app.CircleButton.Tooltip = {'Single click to create shape, double click to complete shape'};
            app.CircleButton.Text = 'Circle';
            app.RectangleButton = uiradiobutton(app.ButtonGroup);
            app.RectangleButton.Enable = 'off';
            app.RectangleButton.Tooltip = {'Single click to create shape, double click to complete shape'};
            app.RectangleButton.Text = 'Rectangle';
            app.RectangleButton.Value = true;
            app.PolygonButton = uiradiobutton(app.ButtonGroup);
            app.PolygonButton.Enable = 'off';
            app.PolygonButton.Tooltip = {'Single click to create shape, double click to complete shape'};
            app.PolygonButton.Text = 'Polygon';
            app.FreehandButton = uiradiobutton(app.ButtonGroup);
            app.FreehandButton.Enable = 'off';
            app.FreehandButton.Tooltip = {'Single click to create shape, double click to complete shape'};
            app.FreehandButton.Text = 'Freehand';
            app.radiusPanel = uipanel(app.MaskCropTab);
            app.radiusPanel.Enable = 'off';
            app.radiusPanel.Visible = 'off';
            app.RadiusEditFieldLabel = uilabel(app.radiusPanel);
            app.RadiusEditFieldLabel.Enable = 'off';
            app.RadiusEditFieldLabel.Text = 'Radius ';
            app.RadiusEditField = uieditfield(app.radiusPanel, 'numeric');
            app.RadiusEditField.Editable = 'off';
            app.RadiusEditField.Enable = 'off';
            app.DrawmaskButton = uibutton(app.MaskCropTab, 'push');
            app.DrawmaskButton.ButtonPushedFcn = createCallbackFcn(app, @DrawmaskButtonPushed, true);
            app.DrawmaskButton.Enable = 'off';
            app.DrawmaskButton.Text = 'Draw mask';
            app.rectPanel = uipanel(app.MaskCropTab);
            app.rectPanel.Enable = 'off';
            app.HeightEditFieldLabel = uilabel(app.rectPanel);
            app.HeightEditFieldLabel.Text = 'Height ';
            app.HeightEditField = uieditfield(app.rectPanel, 'numeric');
            app.HeightEditField.Editable = 'off';
            app.WidthEditFieldLabel = uilabel(app.rectPanel);
            app.WidthEditFieldLabel.Text = 'Width ';
            app.WidthEditField = uieditfield(app.rectPanel, 'numeric');
            app.WidthEditField.Editable = 'off';
            app.CropButton = uibutton(app.MaskCropTab, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Enable = 'off';
            app.CropButton.Text = 'Crop';
            app.AreaEditFieldLabel = uilabel(app.MaskCropTab);
            app.AreaEditFieldLabel.Enable = 'off';
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.MaskCropTab, 'numeric');
            app.AreaEditField.Editable = 'off';
            app.AreaEditField.Enable = 'off';
            app.CroppedFiguresPanel = uipanel(app.MaskCropTab);
            app.CroppedFiguresPanel.Title = 'Cropped Figures';
            app.original_UIAxes_2 = uiaxes(app.CroppedFiguresPanel);
            title(app.original_UIAxes_2, 'Original Image')
            app.original_UIAxes_2.Visible = 'off';
            app.cropped_UIAxes = uiaxes(app.CroppedFiguresPanel);
            title(app.cropped_UIAxes, 'Cropped Image')
            app.cropped_UIAxes.Visible = 'off';
            app.BinariseTab = uitab(app.TabGroup);
            app.BinariseTab.Title = 'Binarise';
            app.CardiffUniversityLlywelynHughes2023Label_4 = uilabel(app.BinariseTab);
            app.CardiffUniversityLlywelynHughes2023Label_4.Text = '© Cardiff University, Llywelyn Hughes. 2023';
            app.GeometrytoanalyseDropDownLabel = uilabel(app.BinariseTab);
            app.GeometrytoanalyseDropDownLabel.Enable = 'off';
            app.GeometrytoanalyseDropDownLabel.Text = {'Geometry to'; 'analyse'};
            app.GeometrytoanalyseDropDown = uidropdown(app.BinariseTab);
            app.GeometrytoanalyseDropDown.Items = {'Light', 'Dark'};
            app.GeometrytoanalyseDropDown.Enable = 'off';
            app.GeometrytoanalyseDropDown.Value = 'Dark';
            app.BinariseButton = uibutton(app.BinariseTab, 'push');
            app.BinariseButton.ButtonPushedFcn = createCallbackFcn(app, @BinariseButtonPushed, true);
            app.BinariseButton.Enable = 'off';
            app.BinariseButton.Text = 'Binarise';
            app.LowerThresholdSliderLabel = uilabel(app.BinariseTab);
            app.LowerThresholdSliderLabel.Enable = 'off';
            app.LowerThresholdSliderLabel.Text = 'Lower Threshold';
            app.GaussianSDLabel = uilabel(app.BinariseTab);
            app.GaussianSDLabel.Enable = 'off';
            app.GaussianSDLabel.Text = 'Gaussian S.D';
            app.LowerThresholdSlider = uislider(app.BinariseTab);
            app.LowerThresholdSlider.Limits = [0 255];
            app.LowerThresholdSlider.MajorTicks = [0 50 100 150 200 250];
            app.LowerThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BinariseButtonPushed, true);
            app.LowerThresholdSlider.Enable = 'off';
            app.LowerThresholdSlider.Value = 75;
            app.GaussianSDSlider = uislider(app.BinariseTab);
            app.GaussianSDSlider.Limits = [0.01 5];
            app.GaussianSDSlider.MajorTicks = [0.01 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.GaussianSDSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianSDSliderValueChanged, true);
            app.GaussianSDSlider.MinorTicks = [0.01 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5];
            app.GaussianSDSlider.Enable = 'off';
            app.GaussianSDSlider.Value = 0.5;
            app.BinarisedFiguresPanel = uipanel(app.BinariseTab);
            app.BinarisedFiguresPanel.Title = 'Binarised Figures';
            app.histogram_UIAxes = uiaxes(app.BinarisedFiguresPanel);
            title(app.histogram_UIAxes, 'Histogram of cropped greyscal image')
            xlabel(app.histogram_UIAxes, 'Pixel Intensity')
            ylabel(app.histogram_UIAxes, 'Count')
            app.histogram_UIAxes.Visible = 'off';
            app.binarised_UIAxes = uiaxes(app.BinarisedFiguresPanel);
            title(app.binarised_UIAxes, 'Binarised Image')
            app.binarised_UIAxes.Visible = 'off';
            app.ThresholdingOptionButtonGroup = uibuttongroup(app.BinariseTab);
            app.ThresholdingOptionButtonGroup.Enable = 'off';
            app.ThresholdingOptionButtonGroup.Title = 'Thresholding Option';
            app.AutomaticButton = uiradiobutton(app.ThresholdingOptionButtonGroup);
            app.AutomaticButton.Enable = 'off';
            app.AutomaticButton.Text = 'Automatic';
            app.AutomaticButton.Value = true;
            app.ManualButton = uiradiobutton(app.ThresholdingOptionButtonGroup);
            app.ManualButton.Enable = 'off';
            app.ManualButton.Text = 'Manual';
            app.ThresholdEditFieldLabel = uilabel(app.BinariseTab);
            app.ThresholdEditFieldLabel.Enable = 'off';
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.BinariseTab, 'numeric');
            app.ThresholdEditField.Editable = 'off';
            app.AnalyseTab = uitab(app.TabGroup);
            app.AnalyseTab.Title = 'Analyse';
            app.CardiffUniversityLlywelynHughes2023Label_2 = uilabel(app.AnalyseTab);
            app.CardiffUniversityLlywelynHughes2023Label_2.Text = '© Cardiff University, Llywelyn Hughes. 2023';
            app.AnalyseButton = uibutton(app.AnalyseTab, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Enable = 'off';
            app.AnalyseButton.Text = 'Analyse';
            app.NumberofRegionsEditFieldLabel = uilabel(app.AnalyseTab);
            app.NumberofRegionsEditFieldLabel.Enable = 'off';
            app.NumberofRegionsEditFieldLabel.Text = 'Number of Regions';
            app.NumberofRegionsEditField = uieditfield(app.AnalyseTab, 'numeric');
            app.NumberofRegionsEditField.Editable = 'off';
            app.NumberofRegionsEditField.Enable = 'off';
            app.AnalysisFiguresPanel = uipanel(app.AnalyseTab);
            app.AnalysisFiguresPanel.Title = 'Analysis Figures';
            app.region_UIAxes = uiaxes(app.AnalysisFiguresPanel);
            title(app.region_UIAxes, 'Region Identification')
            app.region_UIAxes.Visible = 'off';
            app.AnalysisFigureDropDownLabel = uilabel(app.AnalysisFiguresPanel);
            app.AnalysisFigureDropDownLabel.Visible = 'off';
            app.AnalysisFigureDropDownLabel.Text = 'Analysis Figure';
            app.AnalysisFigureDropDown = uidropdown(app.AnalysisFiguresPanel);
            app.AnalysisFigureDropDown.Items = {'Cropped', 'Binarised'};
            app.AnalysisFigureDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisFigureDropDownValueChanged, true);
            app.AnalysisFigureDropDown.Visible = 'off';
            app.AnalysisFigureDropDown.Value = 'Cropped';
            app.ClearAnalysisButton = uibutton(app.AnalyseTab, 'push');
            app.ClearAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAnalysisButtonPushed, true);
            app.ClearAnalysisButton.Enable = 'off';
            app.ClearAnalysisButton.Text = 'Clear Analysis';
            app.AverageRegionAreaEditFieldLabel = uilabel(app.AnalyseTab);
            app.AverageRegionAreaEditFieldLabel.Enable = 'off';
            app.AverageRegionAreaEditFieldLabel.Text = 'Average Region Area';
            app.AverageRegionAreaEditField = uieditfield(app.AnalyseTab, 'numeric');
            app.AverageRegionAreaEditField.Editable = 'off';
            app.AverageRegionAreaEditField.Enable = 'off';
            app.TotalRegionAreaEditFieldLabel = uilabel(app.AnalyseTab);
            app.TotalRegionAreaEditFieldLabel.Enable = 'off';
            app.TotalRegionAreaEditFieldLabel.Text = 'Total Region Area';
            app.TotalRegionAreaEditField = uieditfield(app.AnalyseTab, 'numeric');
            app.TotalRegionAreaEditField.Editable = 'off';
            app.TotalRegionAreaEditField.Enable = 'off';
            app.AnalysisAreaThresholdSliderLabel = uilabel(app.AnalyseTab);
            app.AnalysisAreaThresholdSliderLabel.Tag = 'Ignore regions with area less than selected value';
            app.AnalysisAreaThresholdSliderLabel.Enable = 'off';
            app.AnalysisAreaThresholdSliderLabel.Text = 'Analysis Area Threshold';
            app.AnalysisAreaThresholdSlider = uislider(app.AnalyseTab);
            app.AnalysisAreaThresholdSlider.Limits = [0 500];
            app.AnalysisAreaThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalysisAreaThresholdSlider.Enable = 'off';
            app.AnalysisAreaThresholdSlider.Value = 10;
            app.AverageRegionDiameterEditFieldLabel = uilabel(app.AnalyseTab);
            app.AverageRegionDiameterEditFieldLabel.Enable = 'off';
            app.AverageRegionDiameterEditFieldLabel.Text = 'Average Region Diameter';
            app.AverageRegionDiameterEditField = uieditfield(app.AnalyseTab, 'numeric');
            app.AverageRegionDiameterEditField.Editable = 'off';
            app.AverageRegionDiameterEditField.Enable = 'off';
            app.PorosityEditFieldLabel = uilabel(app.AnalyseTab);
            app.PorosityEditFieldLabel.Enable = 'off';
            app.PorosityEditFieldLabel.Text = 'Porosity (%)';
            app.PorosityEditField = uieditfield(app.AnalyseTab, 'numeric');
            app.PorosityEditField.Editable = 'off';
            app.PorosityEditField.Enable = 'off';
            app.AnalysisUITable = uitable(app.AnalyseTab);
            app.AnalysisUITable.ColumnName = {'Region'; 'Mean Intensity'; 'Area (Unit^2)'; 'Perimeter (Unit)'; 'Centroid (Unit)'; 'Diameter(Unit)'};
            app.AnalysisUITable.RowName = {};
            app.AnalysisUITable.Enable = 'off';
            app.HeatmapsTab = uitab(app.TabGroup);
            app.HeatmapsTab.Title = 'Heatmaps';
            app.HeatmapFiguresPanel = uipanel(app.HeatmapsTab);
            app.HeatmapFiguresPanel.Title = 'Heatmap Figures';
            app.areaHM_UIAxes = uiaxes(app.HeatmapFiguresPanel);
            title(app.areaHM_UIAxes, 'Area Heatmap')
            app.areaHM_UIAxes.Visible = 'off';
            app.diaHM_UIAxes = uiaxes(app.HeatmapFiguresPanel);
            title(app.diaHM_UIAxes, 'Diameter Heatmap')
            app.diaHM_UIAxes.Visible = 'off';
            app.periHM_UIAxes = uiaxes(app.HeatmapFiguresPanel);
            title(app.periHM_UIAxes, 'Perimeter Heatmap')
            app.periHM_UIAxes.Visible = 'off';
            app.HeatmapsButton = uibutton(app.HeatmapsTab, 'push');
            app.HeatmapsButton.ButtonPushedFcn = createCallbackFcn(app, @HeatmapsButtonPushed, true);
            app.HeatmapsButton.Enable = 'off';
            app.HeatmapsButton.Text = 'Heatmaps';
            app.CardiffUniversityLlywelynHughes2023Label_8 = uilabel(app.HeatmapsTab);
            app.CardiffUniversityLlywelynHughes2023Label_8.Text = '© Cardiff University, Llywelyn Hughes. 2023';
            app.HistogramsTab = uitab(app.TabGroup);
            app.HistogramsTab.Title = 'Histograms';
            app.FiguresPanel = uipanel(app.HistogramsTab);
            app.FiguresPanel.Title = ' Figures';
            app.AreaHist_UIAxes = uiaxes(app.FiguresPanel);
            title(app.AreaHist_UIAxes, 'Area Histogram')
            ylabel(app.AreaHist_UIAxes, 'Count')
            app.AreaHist_UIAxes.Visible = 'off';
            app.DiaHist_UIAxes = uiaxes(app.FiguresPanel);
            title(app.DiaHist_UIAxes, 'Diameter Histogram')
            ylabel(app.DiaHist_UIAxes, 'Count')
            app.DiaHist_UIAxes.Visible = 'off';
            app.PeriHist_UIAxes = uiaxes(app.FiguresPanel);
            title(app.PeriHist_UIAxes, 'Perimeter Histogram')
            ylabel(app.PeriHist_UIAxes, 'Count')
            app.PeriHist_UIAxes.Visible = 'off';
            app.CardiffUniversityLlywelynHughes2023Label_3 = uilabel(app.HistogramsTab);
            app.CardiffUniversityLlywelynHughes2023Label_3.Text = '© Cardiff University, Llywelyn Hughes. 2023';
            app.ManualMeasurmentsTab = uitab(app.TabGroup);
            app.ManualMeasurmentsTab.Title = 'Manual Measurments';
            app.CardiffUniversityLlywelynHughes2023Label = uilabel(app.ManualMeasurmentsTab);
            app.CardiffUniversityLlywelynHughes2023Label.Text = '© Cardiff University, Llywelyn Hughes. 2023';
            app.DetectEdgesButton = uibutton(app.ManualMeasurmentsTab, 'push');
            app.DetectEdgesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectEdgesButtonPushed, true);
            app.DetectEdgesButton.Enable = 'off';
            app.DetectEdgesButton.Text = 'Detect Edges';
            app.DrawLineButton = uibutton(app.ManualMeasurmentsTab, 'push');
            app.DrawLineButton.ButtonPushedFcn = createCallbackFcn(app, @DrawLineButtonPushed, true);
            app.DrawLineButton.Enable = 'off';
            app.DrawLineButton.Text = 'Draw Line';
            app.ClearMeasurementsButton = uibutton(app.ManualMeasurmentsTab, 'push');
            app.ClearMeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMeasurementsButtonPushed, true);
            app.ClearMeasurementsButton.Enable = 'off';
            app.ClearMeasurementsButton.Text = 'Clear Measurements';
            app.ManualMeasurementsPanel = uipanel(app.ManualMeasurmentsTab);
            app.ManualMeasurementsPanel.Title = 'Manual Measurements';
            app.EdgeDetection_UIAxes = uiaxes(app.ManualMeasurementsPanel);
            title(app.EdgeDetection_UIAxes, 'Edge Detection')
            app.EdgeDetection_UIAxes.Visible = 'off';
            app.PressEscwhenfinishedmeasuringLabel = uilabel(app.ManualMeasurementsPanel);
            app.PressEscwhenfinishedmeasuringLabel.Visible = 'off';
            app.PressEscwhenfinishedmeasuringLabel.Text = 'Press Esc. when finished measuring';
            app.EdgesThresholdLabel = uilabel(app.ManualMeasurmentsTab);
            app.EdgesThresholdLabel.Enable = 'off';
            app.EdgesThresholdLabel.Text = {'Edges'; 'Threshold'};
            app.EdgesThresholdSlider = uislider(app.ManualMeasurmentsTab);
            app.EdgesThresholdSlider.Limits = [0 1];
            app.EdgesThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @DetectEdgesButtonPushed, true);
            app.EdgesThresholdSlider.Enable = 'off';
            app.MeasurementsUITable = uitable(app.ManualMeasurmentsTab);
            app.MeasurementsUITable.ColumnName = {'Line'; 'Length (pixels)'; 'Length (Unit)'};
            app.MeasurementsUITable.RowName = {};
            app.MeasurementsUITable.Enable = 'off';
            app.CADComparisonTab = uitab(app.TabGroup);
            app.CADComparisonTab.Title = 'CAD Comparison ';
            app.ImportCADImageButton = uibutton(app.CADComparisonTab, 'push');
            app.ImportCADImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportCADImageButtonPushed, true);
            app.ImportCADImageButton.Enable = 'off';
            app.ImportCADImageButton.Text = 'Import CAD Image';
            app.CADFilenameEditField = uieditfield(app.CADComparisonTab, 'text');
            app.CADFilenameEditField.Editable = 'off';
            app.CADFilenameEditField.Enable = 'off';
            app.CADFilenameEditFieldLabel = uilabel(app.CADComparisonTab);
            app.CADFilenameEditFieldLabel.Enable = 'off';
            app.CADFilenameEditFieldLabel.Text = 'CAD File name';
            app.CADComparisonFiguresPanel = uipanel(app.CADComparisonTab);
            app.CADComparisonFiguresPanel.Title = 'CAD Comparison Figures';
            app.CAD_UIAxes = uiaxes(app.CADComparisonFiguresPanel);
            app.CAD_UIAxes.XTick = [];
            app.CAD_UIAxes.YTick = [];
            app.CardiffUniversityLlywelynHughes2023Label_7 = uilabel(app.CADComparisonTab);
            app.CardiffUniversityLlywelynHughes2023Label_7.Text = '© Cardiff University, Llywelyn Hughes. 2023';
            app.ScaleCADButton = uibutton(app.CADComparisonTab, 'push');
            app.ScaleCADButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleCADButtonPushed, true);
            app.ScaleCADButton.Enable = 'off';
            app.ScaleCADButton.Text = 'Scale CAD';
            app.OverlayBoundariesButton = uibutton(app.CADComparisonTab, 'push');
            app.OverlayBoundariesButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayBoundariesButtonPushed, true);
            app.OverlayBoundariesButton.Enable = 'off';
            app.OverlayBoundariesButton.Text = 'Overlay Boundaries';
            app.YSliderLabel = uilabel(app.CADComparisonTab);
            app.YSliderLabel.Enable = 'off';
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.CADComparisonTab);
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.YSlider.Enable = 'off';
            app.XSlider_2Label = uilabel(app.CADComparisonTab);
            app.XSlider_2Label.Enable = 'off';
            app.XSlider_2Label.Text = 'X ';
            app.XSlider = uislider(app.CADComparisonTab);
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.Enable = 'off';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.CardiffUniversityLlywelynHughes2022Label_11 = uilabel(app.ExportTab);
            app.CardiffUniversityLlywelynHughes2022Label_11.Text = '© Cardiff University, Llywelyn Hughes. 2022';
            app.FigureExportPanel = uipanel(app.ExportTab);
            app.FigureExportPanel.Title = 'Figure Export';
            app.SaveAnalysisTableButton = uibutton(app.ExportTab, 'push');
            app.SaveAnalysisTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisTableButtonPushed, true);
            app.SaveAnalysisTableButton.Enable = 'off';
            app.SaveAnalysisTableButton.Text = 'Save  Analysis Table';
            app.SaveMeasurementTableButton = uibutton(app.ExportTab, 'push');
            app.SaveMeasurementTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMeasurementsTableButtonPushed, true);
            app.SaveMeasurementTableButton.Enable = 'off';
            app.SaveMeasurementTableButton.Text = 'Save Measurement Table';
            app.FigureDropDown = uidropdown(app.ExportTab);
            app.FigureDropDown.Items = {'Original', 'Greyscale', 'Cropped', 'Binary', 'Histogram', 'Region', 'Heatmap (Area)', 'Heatmap (Diameter)', 'Heatmap (Perimeter)', 'Area Histogram', 'Diameter Histogram', 'Perimeter Histogram', 'Manual Measurements', 'CAD'};
            app.FigureDropDown.ValueChangedFcn = createCallbackFcn(app, @FigureDropDownValueChanged, true);
            app.FigureDropDown.Enable = 'off';
            app.FigureDropDown.Value = 'Original';
            app.SaveFigureButton = uibutton(app.ExportTab, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Enable = 'off';
            app.SaveFigureButton.Text = 'Save Figure';
            app.SaveAllButton = uibutton(app.ExportTab, 'push');
            app.SaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllButtonPushed, true);
            app.SaveAllButton.Enable = 'off';
            app.SaveAllButton.Text = 'Save All';
            app.ResetButton_2 = uibutton(app.ExportTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.Image_2 = uiimage(app.ExportTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'LAT_Logo.PNG');
            app.SaveAllPanel = uipanel(app.UIFigure);
            app.SaveAllPanel.Title = 'Save All ';
            app.SaveAllPanel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LAT_v2_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end